classdef CP_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DecodeButton                 matlab.ui.control.Button
        txt_audio_path               matlab.ui.control.EditField
        AudioFilePathEditFieldLabel  matlab.ui.control.Label
        OutputTextAreaLabel          matlab.ui.control.Label
        text_area                    matlab.ui.control.TextArea
        nxt_btn                      matlab.ui.control.Button
        prev_btn                     matlab.ui.control.Button
        plot_n_f_sig                 matlab.ui.control.UIAxes
        plot_n_t_sig                 matlab.ui.control.UIAxes
        plot_m_signal                matlab.ui.control.UIAxes
    end

    methods (Access = private)
        
        % -- Get Signal Window ---------------------------------------------------------------------
        
        function divs = noteparse(app, data)

            len = length(data);
            
            %let's find a threshold value so we know when a note starts/stops
        
            threshup = 0.2 * max(data);  % 20% of the maximum value
            threshdown = 0.04 * max(data);
            
            quiet=1;  % a flag so we know if we're noisy or quiet right now
            j=1;
            
            
            for i = 51 : len - 50
            
               % Starting of the Note...
               if quiet == 1  % we're trying find the begining of a note
        
                  if (max(abs(data(i-50:i+50))) > threshup)
        
                     quiet = 0;  % we found it
                     divs(j) = i;  %record this division point
                     j=j+1;
        
                  end
        
               % Ending of the Note....
               else
        
                  if (max(abs(data(i-50:i+50))) < threshdown)
        
                     quiet = 1;  %note's over
                     divs(j) = i;
                     j=j+1;
        
                  end
        
               end
        
            end
        
        end
   
        function w = notewindows(app, data)
        
            % this is basically just a nice,pretty wrapper for noteparse
            
            divs = noteparse(app, data);
            
            % let's first massage the intervals by adding the beginning and end points
            
            d2(1) = 0;
            
            for i=1:length(divs)
        
                d2(i+1)=divs(i);
        
            end
        
            d2(length(divs)+2) = length(data);
            
            for i=1:length(d2)/2
        
                w(i) = (d2(2 * i - 1) + d2(2 * i)) / 2;
        
            end
        
        end

        
        % --- Updated Results Show -------
        
        function [note, octave] = getNoteName(app, freq)
            
            % estimate musical note name and octave from frequency using lookup table
        
            % define table of note names and frequencies for octave 4
            notes = {'C', 'C#', 'D', 'D#', 'E', 'F', 'F#', 'G', 'G#', 'A', 'A#', 'B'};
            fref = [261.63, 277.18, 293.66, 311.13, 329.63, 349.23, 369.99, 392.00, 415.30, 440.00, 466.16, 493.88];
        
            % find the closest note in the table to the given frequency
            [~, idx] = min(abs(fref - freq));
        
            % calculate the octave relative to middle C (C4)
            octave = floor(log2(freq / 261.63)) - 1;
        
            % calculate the note name from the table index and add the octave number
            note = notes{idx};
        
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            
            % Process Initialization Status....
            app.text_area.Value = 'Processing....';

            path = strip(app.txt_audio_path.Value);
            
            global data;
            [data, Fs] = audioread(path);

            % Printing the output in the text area...
            app.text_area.Value = 'Output Notes with their Frequency';

            % Plotting the Input Signal....
            plot(app.plot_m_signal, data);
            
            % get the note windows
            global w;
            w = notewindows(app, data);

            % how many notes in this sample
            num_notes = length(w)-1;

            % Declaring graph count...
            global count;
            count = 1;
                        
            for i=1:num_notes

                %take the window for the current note
                cur_note = data(w(i) : w(i + 1));

                len = length(cur_note);
                
                % Plotting the First Graph....
                
                if(count == 1)

                    plot(app.plot_n_t_sig, cur_note)

                end           


                % Calculating the Fast Furior Transform of the Note.....
                % Converting from Time Domain to Frequency Domain....
                cur_fft = abs(fft(cur_note));
                cur_fft = cur_fft(1 : len / 2 + 1);

                % Plotting the First Graph....
                
                if(count == 1)

                    plot(app.plot_n_f_sig, cur_fft);
                    count = count + 1;

                end
           

                % Finding the maximum Peak and Converting it into Frequency.....
                [~, I] = max(cur_fft);
                I = I * Fs / len;
            
            
                % the bandpass function is used to filter out noise from the audio signal, 
                % by retaining only frequencies within a narrow band centered on the fundamental 
                % frequency of each note.
                data = bandpass(data, [I - 0.1, I + 0.1], Fs);
            
                cur_n = data(w(i) : w(i + 1));
                len=length(cur_n);
            
                for k=1:(len/2-1)
            
                   if(abs(cur_fft(k) - cur_fft(k + 1)) > 100)
            
                        I=k;
                        break;
            
                   end
            
                end
            
                % Adding Freqency of Each note to the freq array...
                % Adding the note freq only if its a approiate note / signal.....
                if ((I > 20) && (I < 20000))
            
                    freq(i) = I * Fs/len;
            
                end
            
            end

            % -- Printing all Frequencies ---
            app.text_area.Value = [app.text_area.Value; sprintf(' ')];
            app.text_area.Value = [app.text_area.Value; sprintf('Number of Notes Found...')];
            app.text_area.Value = [app.text_area.Value; num2str(num_notes)];
            app.text_area.Value = [app.text_area.Value; sprintf(' ')];
            app.text_area.Value = [app.text_area.Value; 'Frequecies of all Notes:'];
            app.text_area.Value = [app.text_area.Value; num2str(freq)];
            app.text_area.Value = [app.text_area.Value; sprintf(' ')];

            % -- Printing the final Output....

            for i = 1 : length(freq)
            
                % Finding the Pitch...
                [p, o] = getNoteName(app, freq(i));
                
                app.text_area.Value = [app.text_area.Value; num2str(p)];
                app.text_area.Value = [app.text_area.Value; num2str(o+4)];
                app.text_area.Value = [app.text_area.Value; num2str(freq(i))];
                app.text_area.Value = [app.text_area.Value; sprintf(' ')];
            
            end


        end

        % Button pushed function: prev_btn
        function prev_btnPushed(app, event)
            
            global w;
            global count;
            global data;

            if (count > 1)

                %take the window for the current note
                cur_note = data(w(count) : w(count + 1));

                cur_fft = abs(fft(cur_note));
                cur_fft = cur_fft(1 : length(cur_note) / 2 + 1);
    
                % Plotting the Note Signals....
                plot(app.plot_n_t_sig, cur_note)
                plot(app.plot_n_f_sig, cur_fft);

            end
            
            if (count ~= 1)

                count = count - 1;

            end
            

        end

        % Button pushed function: nxt_btn
        function nxt_btnPushed(app, event)
            
            global w;
            global count;
            global data;

            if (count < length(w))

                %take the window for the current note
                cur_note = data(w(count) : w(count + 1));

                cur_fft = abs(fft(cur_note));
                cur_fft = cur_fft(1 : length(cur_note) / 2 + 1);
    
                % Plotting the Note Signals....
                plot(app.plot_n_t_sig, cur_note)
                plot(app.plot_n_f_sig, cur_fft);

            end

            count = count + 1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot_m_signal = uiaxes(app.UIFigure);
            title(app.plot_m_signal, 'Input Audio Signal')
            xlabel(app.plot_m_signal, 'Frequency')
            ylabel(app.plot_m_signal, 'Amplitude')
            zlabel(app.plot_m_signal, 'Z')
            app.plot_n_t_sig = uiaxes(app.UIFigure);
            title(app.plot_n_t_sig, 'Note Signal')
            xlabel(app.plot_n_t_sig, 'Frequency')
            ylabel(app.plot_n_t_sig, 'Amplitude')
            zlabel(app.plot_n_t_sig, 'Z')
            app.plot_n_f_sig = uiaxes(app.UIFigure);
            title(app.plot_n_f_sig, 'Magnitude Spectrum')
            xlabel(app.plot_n_f_sig, 'Frequency')
            ylabel(app.plot_n_f_sig, '|X(f)|')
            zlabel(app.plot_n_f_sig, 'Z')
            app.prev_btn = uibutton(app.UIFigure, 'push');
            app.prev_btn.ButtonPushedFcn = createCallbackFcn(app, @prev_btnPushed, true);
            app.prev_btn.Text = '<';
            app.nxt_btn = uibutton(app.UIFigure, 'push');
            app.nxt_btn.ButtonPushedFcn = createCallbackFcn(app, @nxt_btnPushed, true);
            app.nxt_btn.Text = '>';
            app.text_area = uitextarea(app.UIFigure);
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.AudioFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.AudioFilePathEditFieldLabel.Text = 'Audio File Path';
            app.txt_audio_path = uieditfield(app.UIFigure, 'text');
            app.DecodeButton = uibutton(app.UIFigure, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CP_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end